classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CarregarImagemButton            matlab.ui.control.Button
        ModificadoLabel                 matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Trabalho1FiltrosetcnicasbsicasLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AlgoritmosTcnicasDropDown       matlab.ui.control.DropDown
        AlgoritmosTcnicasDropDownLabel  matlab.ui.control.Label
        ProcessarButton                 matlab.ui.control.Button
    end

    
    methods (Access = private)
        
       % Função para converter RGB em escala de cinza
        function out = grayscale(app,img_rgb)
            [lin, col, ~] = size(img_rgb);
            img_gray = zeros(lin, col);
            for i = 1:lin
                for j = 1:col
                    R = double(img_rgb(i,j,1));
                    G = double(img_rgb(i,j,2));
                    B = double(img_rgb(i,j,3));
                    img_gray(i,j) = 0.3 * R + 0.6 * G + 0.1 * B;
                end
            end
            img_gray = uint8(img_gray);
            out = img_gray;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            % Abre o seletor de arquivos e permite escolher uma imagem
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Imagens (.jpg,.png, .bmp,.tif)'}, ...
                                      'Selecionar uma imagem');
        
            % Verifica se o usuário selecionou um arquivo
            if isequal(file, 0)
                disp('Nenhum arquivo selecionado.');
                return;
            end
        
            % Lê a imagem do caminho selecionado
            img = imread(fullfile(path, file));
        
            % Define a imagem no componente uiimage
            app.Image.ImageSource = img;
        
            % (Opcional) Exibe uma mensagem no console
            disp(['Imagem carregada: ', fullfile(path, file)]);
        end

        % Button pushed function: ProcessarButton
        function ProcessarButtonPushed(app, event)
            switch app.AlgoritmosTcnicasDropDown.Value
                case 'Escala de Cinza'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;
                 % imshow(aux);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessarButton = uibutton(app.UIFigure, 'push');
            app.ProcessarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.ProcessarButton.Text = 'Processar';
            app.AlgoritmosTcnicasDropDownLabel = uilabel(app.UIFigure);
            app.AlgoritmosTcnicasDropDownLabel.Text = 'Algoritmos/Técnicas';
            app.AlgoritmosTcnicasDropDown = uidropdown(app.UIFigure);
            app.AlgoritmosTcnicasDropDown.Items = {'Limiarização', 'Escala de Cinza', 'Passa-Alta básico', 'Passa-Alta Alto reforço'};
            app.AlgoritmosTcnicasDropDown.Value = 'Limiarização';
            app.Image = uiimage(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel = uilabel(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel.Text = 'Trabalho 1 - Filtros e técnicas básicas';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>';
            app.Image_2 = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.ModificadoLabel = uilabel(app.UIFigure);
            app.ModificadoLabel.Text = 'Modificado';
            app.CarregarImagemButton = uibutton(app.UIFigure, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end